classdef SFERA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AddeffectButton   matlab.ui.control.Button
        TabGroup          matlab.ui.container.TabGroup
        SIMULATIONTab     matlab.ui.container.Tab
        TestButton        matlab.ui.control.Button
        RunoptionsButton  matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        RunButton         matlab.ui.control.Button
        PauseButton       matlab.ui.control.Button
        ContinueButton    matlab.ui.control.Button
        UITable           matlab.ui.control.Table
        LoadModelButton   matlab.ui.control.Button
        SaveOutputButton  simulink.ui.control.SaveOutputButton
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    % ----- Private properties -----
    properties (Access = private)
        ModelPath
        modelName
    end
    
    % ----- Public properties -----
    properties (Access = public)
        % Segmentation
        segmentationDisplay = ["derivative-based(default)", "sliding-window", "matlab", "uniform"];
        segmentationFcns = {@dySegmentation, @swSegmentation, @matlabSegmentation, @uniformSegmentation};
        segmentationChoice = "derivative-based(default)";
        segmentationFcn = @dySegmentation;
    
        % Time window
        timeWindowDisplay = ["auto(default)", "fixed time", "fault detection"];
        timeWindowChoice = "auto(default)";   % default
        startTime = 0;               % used only if fixed time
        endTime = 10;                % used only if fixed time

        % Effects names
        effectsNames = {'Exponential Divergence', 'Damped Oscillations', 'Permanent Oscillations', 'Divergent Oscillations', 'Sum of Sinusoids', 'Runge Trend', 'Arctangent Trend', 'Cubic Radix Trend'};
        
        % Table Data dimensions
        lenTable 
        colTable
    end


    % ----- Methods -----
    %methods (Access = private)
        
        
        % ----- ADD NEW EFFECTS -----
        % function confirmEffect(app, dialog)
        %     effectName = dialog.UserData.effectField.Value;
        %     paramValue = dialog.UserData.paramField.Value;
        % 
        %     delete(dialog);
        % 
        %     if isempty(effectName)
        %         uialert(app.UIFigure, 'Name field cannot be empty', 'Error');
        %         return;
        %     end
        %     existingEffects = app.UITable.Data(:, 3); 
        %     if any(strcmp(existingEffects, effectName))
        %         uialert(app.UIFigure, 'Name exists yet.', 'Error');
        %         return;
        %     end
        % 
        %     try
        %         paramArray = str2num(paramValue);  
        %         if isempty(paramArray) || ~isnumeric(paramArray)
        %             error('Format not valid');
        %         end
        %     catch
        %         uialert(app.UIFigure, 'Parameters must be a integer or double array.', 'Error');
        %     end
        % 
        %     % Actual data
        %     currentData = app.UITable.Data;
        %     newData = currentData;
        %     rowIdx = size(currentData, 1) + 1;
        % 
        %     faultIndices = find(~cellfun(@isempty, currentData(:, 1)));
        % 
        %     % Add new row for each fault
        %     for i = 1:length(faultIndices)
        %         newData{rowIdx, 1} = '';  
        %         newData{rowIdx, 2} = '';  
        %         newData{rowIdx, 3} = effectName;  
        %         newData{rowIdx, 4} = false;  
        %         newData{rowIdx, 5} = ''; 
        % 
        %         rowIdx = rowIdx + 1;
        %     end
        % 
        %     % Update table
        %     app.UITable.Data = newData;       
        % end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PauseButton.Visible = 'off';
            app.ContinueButton.Visible = 'off';
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [file, path] = uigetfile({'*.slx;*.mdl', 'Simulink Models (*.slx, *.mdl)'}, ...
                                     'Select a Simulink Model');
    
            % Correct file
            if file ~= 0
                app.ModelPath = fullfile(path, file);            
                % open_system(app.ModelPath);
                [~, app.modelName, ~] = fileparts(app.ModelPath);
            end

            desktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            modelGroup = desktop.getGroupContainer(app.modelName);
            
            if ~isempty(modelGroup)
                modelFrame = modelGroup.getTopLevelAncestor;
                modelFrame.setState(java.awt.Frame.ICONIFIED);
            end

            % Initialize Table
            createFaultTable(app);

            % ----- CREATE TABLE -----
            function createFaultTable(app)
                % Load the model, if it's not opened yet
                if ~bdIsLoaded(app.modelName)
                    load_system(app.modelName);
                end
            
                % find faults in the model
                faults = Simulink.fault.findFaults(app.modelName);
            
                % No faults found
                if isempty(faults)
                    disp("No fault found.")
                    app.UITable.Data = {}; % Empty table
                    return;
                end
              
                % Create the structure for the table
                tableData = cell(1000,5);
                rowIdx = 1;  
                
                for i = 1:length(faults)
                    faultName = faults(i).Name;    
                    isActive = faults(i).IsActive; 
                    
                    % Principal row for fault data
                    tableData{rowIdx, 1} = faultName;
                    tableData{rowIdx, 2} = isActive;
                    tableData{rowIdx, 4} = '';
    
                    rowIdx = rowIdx + 1; 
                    
                    % Sub-rows for effects analysis 
                    for j = 1:length(app.effectsNames)
                        tableData{rowIdx, 1} = '';  
                        tableData{rowIdx, 2} = '';  
                        tableData{rowIdx, 3} = app.effectsNames{j};  
                        tableData{rowIdx, 4} = false;
                        tableData{rowIdx, 5} = '';  
    
                        rowIdx = rowIdx + 1; 
                    end
                end

                app.lenTable = rowIdx;
                app.colTable = 5;
    
                app.UITable.Data = tableData;
                app.UITable.ColumnEditable = [false, true, false, true, false];
            end

        end

        % Button pushed function: AddeffectButton
        function AddeffectButtonPushed(app, event)
            warning('Not implemented yet.');
            
            %New effect to check
            % d = uifigure('Name', 'Add new effect...', 'Position', [450 450 300 200]);
            % 
            % uilabel(d, 'Text', 'Effect Name:', 'Position', [20 150 100 20]);
            % effectField = uieditfield(d, 'text', 'Position', [130 150 150 22]);
            % 
            % uilabel(d, 'Text', 'Parameters [x, y, z]:', 'Position', [20 110 100 20]);
            % paramField = uieditfield(d, 'text', 'Position', [130 110 150 22]);
            % 
            % d.UserData.effectField = effectField;
            % d.UserData.paramField = paramField;
            % 
            % % Confirm Button
            % uibutton(d, 'Text', 'Add', 'Position', [100 50 100 30], ...
            %     'ButtonPushedFcn', @(btn, event) confirmEffect(app, d));  
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.modelName)
                uialert(app.UIFigure, 'Load a model before starting the simulation.', 'Error');
                return;
            end
            
            app.PauseButton.Visible = 'on';
            app.RunButton.Visible = 'off';

            faults = Simulink.fault.findFaults(app.modelName);
            
            for i = 1:length(faults) 
                            
                if faults(i).IsActive
                    % === 1. Compute difference between signals ===

                    % --- Simulation with fault off ---
                    Simulink.fault.enable(faults(i).ModelElement,false);
                    simIn1  = Simulink.SimulationInput(app.modelName);
                    simIn1 = simIn1.setModelParameter('StopTime', num2str(app.endTime));
                    simOut1 = sim(simIn1);
            
                    % --- Simulation with fault ON ---
                    Simulink.fault.enable(faults(i).ModelElement,true);
                    simIn2  = Simulink.SimulationInput(app.modelName);
                    simIn2 = simIn2.setModelParameter('StopTime', num2str(app.endTime));
                    simOut2 = sim(simIn2);
                    
                    % --- Estraction logged signals ---
                    t  = simOut1.logsout{1}.Values.Time;
                    y1 = simOut1.logsout{1}.Values.Data; 
                    y2 = simOut2.logsout{1}.Values.Data;
                    
                    % --- Difference ---
                    yDiff = y1 - y2;

                    % === 2. Preprocessing and Segmentation ===

                    % --- Time window ---
                    if app.timeWindowChoice == "fixed time"
                        idxStart = find(t >= app.startTime, 1, 'first'); 
                        idxEnd   = find(t <= app.endTime, 1, 'last');  
                    elseif app.timeWindowChoice == "auto(default)"
                        if faults(i).TriggerType =="Always On"
                            idxStart = 1;
                            idxEnd = length(yDiff);  
                        elseif faults(i).TriggerType == "Timed"
                            idxStart = faults(i).StartTime;
                            idxEnd = length(yDiff);  % end fault effect
                        else
                            disp('Conditional');
                        end
                    else
                        idxStart = 1;
                        idxEnd = length(yDiff);
                    end

                    yWin = yDiff(idxStart:idxEnd);
                    tWin = t(idxStart:idxEnd);

                    % --- Cleaning signal ---
                    %[y, ~] = cleanSignal(yWin, 'adaptive');

                    chosenSegmentation = app.segmentationFcn;
                    % --- Compute changing points ---
                    changePts = chosenSegmentation(yWin, tWin);

                    % --- create segments ---
                    segments = cell(length(changePts)-1,1);
                    for k = 1:length(segments)
                        segments{k} = yWin(changePts(k):changePts(k+1)-1);
                    end


                    % === 3. Analysis ===
                    N = length(segments);
                    result = cell(N, 2);
                    
         
                    for k = 1:N
                        seg = segments{k};
                        [isDiv, howDiv] = isDivergent(seg);
                        result{k,1} = isDiv;
                        result{k,2} = howDiv;
                        
                        if isDiv
                            % row corresponding to current fault
                            j = find(strcmp(faults(i).Name, app.UITable.Data(:,1)), 1);
                            if ~isempty(j)
                                % effects rows
                                mIdx = j+1 : j+length(app.effectsNames);
                                m = find(strcmp(howDiv, app.UITable.Data(mIdx,3)), 1);
           
                                if ~isempty(mIdx(m)) && (app.UITable.Data{mIdx(m),4})
                                    % Update 5° column
                                    app.UITable.Data{mIdx(m),5} = true;
                                end
                            end
                            
                        end
                    end
       
                end
            end

            
            app.PauseButton.Visible = 'off';
            app.RunButton.Visible = 'on';
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            set_param(app.modelName, 'SimulationCommand', 'pause');
            app.PauseButton.Visible = 'off';
            app.ContinueButton.Visible = 'on';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            set_param(app.modelName, 'SimulationCommand', 'continue');
            app.ContinueButton.Visible = 'off';
            app.PauseButton.Visible = 'on';
        end

        % Button pushed function: RunoptionsButton
        function RunoptionsButtonPushed(app, event)
            % --- Available options ---
            segmentationOptions = app.segmentationDisplay;   
            timeWindowOptions = app.timeWindowDisplay;       
        
            % --- Dialog window ---
            dlg = uifigure('Name', 'Run options', ...
                           'Position', [500 300 450 300]);
        
            gl = uigridlayout(dlg, [4,1]);
            gl.RowHeight = {60, 60, 90, 40};
            gl.Padding = [10 5 10 5];
        
            % --- Description ---
            uilabel(gl, ...
                'Text', ['Here you can configure how the analysis will be executed. ' ...
                         'You can choose the time window of interest (by default, the analysis starts from the beginning) ' ...
                         'and, as an advanced option, you can select the segmentation logic to apply.'], ...
                'FontWeight', 'normal', ...
                'WordWrap', 'on');
        
            % --- Segmentation row ---
            segLayout = uigridlayout(gl, [1,2]);
            segLayout.RowHeight = {22};
            segLayout.ColumnWidth = {100, '1x'};
            uilabel(segLayout, 'Text', 'Segmentation: ', 'HorizontalAlignment', 'right', 'FontWeight', 'bold');
            segDrop = uidropdown(segLayout, ...
                'Items', segmentationOptions, ...
                'Value', app.segmentationChoice);   
        
            % --- Time window row ---
            timeLayout = uigridlayout(gl, [1,2]);
            timeLayout.RowHeight = {22};
            timeLayout.ColumnWidth = {100, '1x'};
            uilabel(timeLayout, 'Text', 'Time window: ', 'HorizontalAlignment', 'right', 'FontWeight', 'bold');
            timeDrop = uidropdown(timeLayout, ...
                'Items', timeWindowOptions, ...
                'Value', app.timeWindowChoice, ...
                'ValueChangedFcn', @(src,event) toggleFixedTimeFields());
        
            % --- Start/End fields (initially hidden, unless fixed time selected) ---
            startLabel = uilabel(timeLayout, 'Text', 'Start time:', ...
                'HorizontalAlignment', 'right', 'Visible', strcmp(app.timeWindowChoice,'fixed time'));
            startEdit = uieditfield(timeLayout, 'numeric', ...
                'Value', app.startTime, ...
                'Visible', strcmp(app.timeWindowChoice,'fixed time'));
        
            endLabel = uilabel(timeLayout, 'Text', 'End time:', ...
                'HorizontalAlignment', 'right', 'Visible', strcmp(app.timeWindowChoice,'fixed time'));
            endEdit = uieditfield(timeLayout, 'numeric', ...
                'Value', app.endTime, ...
                'Visible', strcmp(app.timeWindowChoice,'fixed time'));
        
            % --- Buttons row ---
            btnLayout = uigridlayout(gl, [1,4]);
            btnLayout.ColumnWidth = {'1x','1x','1x','1x'};
            
            uibutton(btnLayout, 'Text', 'OK', ...
                'ButtonPushedFcn', @(src,event) onOK(app, dlg, segDrop, timeDrop, startEdit, endEdit));
            uibutton(btnLayout, 'Text', 'Cancel', ...
                'ButtonPushedFcn', @(src,event) onCancel(app, dlg));
            uibutton(btnLayout, 'Text', 'Help', ...
                'ButtonPushedFcn', @(src,event) onHelp(dlg));
            uibutton(btnLayout, 'Text', 'Apply', ...
                'ButtonPushedFcn', @(src,event) onApply(app, segDrop.Value, timeDrop.Value, startEdit.Value, endEdit.Value));
        
            % --- Nested functions ---
            function toggleFixedTimeFields()
                % Show/hide numeric fields depending on dropdown value
                if timeDrop.Value == "fixed time"
                    startLabel.Visible = 'on';
                    startEdit.Visible = 'on';
                    endLabel.Visible = 'on';
                    endEdit.Visible = 'on';
                else
                    startLabel.Visible = 'off';
                    startEdit.Visible = 'off';
                    endLabel.Visible = 'off';
                    endEdit.Visible = 'off';
                end
            end
        
            function onApply(app, selectedSegmentation, selectedTimeWindow, startVal, endVal)
                % --- Segmentation ---
                idx = find(app.segmentationDisplay == selectedSegmentation, 1);
                if ~isempty(idx)
                    app.segmentationChoice = selectedSegmentation;
                    app.segmentationFcn = app.segmentationFcns{idx};
                else
                    warning('Selected segmentation not recognized');
                end
        
                % --- Time window ---
                app.timeWindowChoice = selectedTimeWindow;
                if selectedTimeWindow == "fixed time"
                    app.startTime = startVal;
                    app.endTime = endVal;
                else
                    % reset to defaults if not fixed
                    app.startTime = 0;
                    app.endTime = 10;
                end
      
            end
        
            function onOK(app, dlg, segDrop, timeDrop, startEdit, endEdit)
                % Apply before closing
                onApply(app, segDrop.Value, timeDrop.Value, startEdit.Value, endEdit.Value);
                delete(dlg);
            end
        
            function onCancel(app, dlg)
                % Reset to defaults
                app.segmentationChoice = app.segmentationDisplay(1);
                app.segmentationFcn = app.segmentationFcns{1};
                app.timeWindowChoice = "auto";
                app.startTime = 0;
                app.endTime = 10;
                delete(dlg);
            end
        
            function onHelp(dlg)
                uialert(dlg, ['Here you can choose:' newline ...
                              '- the segmentation logic (advanced option)' newline ...
                              '- the time window mode (auto, fixed, or fault detection).'], ...
                              'Help');
            end

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            newValue = event.NewData;
        
            faultName = app.UITable.Data{row,1};

            faults = Simulink.fault.findFaults(app.modelName);
            nf = length(faults);
           
            % Update fault state in the model
            if col == 2
                if newValue
                    % Activate fault
                    for i = 1:nf
                        if strcmp(faultName, faults(i).Name)
                            Simulink.fault.enable(faults(i).ModelElement,true);
                        end
                    end
               
                else
                    % De-activate fault
                    for i = 1:nf
                        if strcmp(faultName, faults(i).Name)
                            Simulink.fault.enable(faults(i).ModelElement,false);
                        end
                    end
                end
            end
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            faults = Simulink.fault.findFaults(app.modelName);
            i = 2;
            howDiv = 'Permanent Oscillations';
            % row corresponding to current fault
            j = find(strcmp(faults(i).Name, app.UITable.Data(:,1)), 1);

            if ~isempty(j)
                % effects rows
                mIdx = j+1 : j+length(app.effectsNames);
                m = find(strcmp(howDiv, app.UITable.Data(mIdx,3)), 1);
                e = app.UITable.Data{mIdx(m),4};
                if ~isempty(mIdx(m)) && (e)
                    % Update 5° column
                    app.UITable.Data{mIdx(m),5} = true;
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param(app.modelName, 'SimulationCommand', 'stop');
            app.RunButton.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SIMULATIONTab = uitab(app.TabGroup);
            app.SIMULATIONTab.Title = 'SIMULATION';
            app.SaveOutputButton = uisimdatabutton('saveoutput', app.SIMULATIONTab);
            app.LoadModelButton = uibutton(app.SIMULATIONTab, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Icon = 'loadInput_24.png';
            app.LoadModelButton.Text = 'Load Model';
            app.UITable = uitable(app.SIMULATIONTab);
            app.UITable.ColumnName = {'Fault Name'; 'Fault Status'; 'Effects'; 'Selection'; 'Detected'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ContinueButton = uibutton(app.SIMULATIONTab, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Icon = 'continue_24.png';
            app.ContinueButton.Text = 'Continue';
            app.PauseButton = uibutton(app.SIMULATIONTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'pause_24.png';
            app.PauseButton.Text = 'Pause';
            app.RunButton = uibutton(app.SIMULATIONTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'play_24.png';
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.SIMULATIONTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop_24.png';
            app.StopButton.Text = 'Stop';
            app.RunoptionsButton = uibutton(app.SIMULATIONTab, 'push');
            app.RunoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @RunoptionsButtonPushed, true);
            app.RunoptionsButton.Text = 'Run options';
            app.TestButton = uibutton(app.SIMULATIONTab, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.AddeffectButton = uibutton(app.UIFigure, 'push');
            app.AddeffectButton.ButtonPushedFcn = createCallbackFcn(app, @AddeffectButtonPushed, true);
            app.AddeffectButton.Text = 'Add effect...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SFERA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end