classdef SFERA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        logArea                 matlab.ui.control.TextArea
        TabGroup                matlab.ui.container.TabGroup
        SIMULATIONTab           matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        faultTuningButton       matlab.ui.control.Button
        InstructionPanel        matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        openModelCheckBox       matlab.ui.control.CheckBox
        Image                   matlab.ui.control.Image
        instructionLabel        matlab.ui.control.Label
        loadModelToStartButton  matlab.ui.control.Button
        gettingStarted          matlab.ui.control.Hyperlink
        whatIsSfera             matlab.ui.control.Hyperlink
        Label                   matlab.ui.control.Label
        Title                   matlab.ui.control.Label
        OKButton                matlab.ui.control.Button
        componentDropDown       matlab.ui.control.DropDown
        loadModelButton         matlab.ui.control.Button
        saveOutputButton        matlab.ui.control.Button
        runButton               matlab.ui.control.Button
        addEffectButton         matlab.ui.control.Button
        runOptionsButton        matlab.ui.control.Button
        stopButton              matlab.ui.control.Button
        pauseButton             matlab.ui.control.Button
        continueButton          matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        RESULTSTab              matlab.ui.container.Tab
        resultTable             matlab.ui.control.Table
        resultAxes              matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    %% === Properties ===
    % These properties store current model informations and user choices
    % and they can be called in different parts of the code as
    % app.propertyname (eg. app.modelPath, app.segmentationChoice)

    properties (Access = private)
        % ----- Private Model Properties -----
        modelPath          % Path to the current model
        modelName          % Name of the loaded model
        component          % Component or subsystem under analysis
        faultsList         % List of defined faults
        faultModel         % Fault model object or structure
        faultInfoXML       % faultInfo.xml associated to the model
    end
    
    % ----- Public Properties -----
    properties (Access = public)
    
        % ---- Segmentation Settings ----
        % Different segmentations can be chosen in 'Run options'
        % window. Default segmentation and default values for
        % 'uniformPercentage' and 'swMetric' are set below and can be
        % changed in 'Run options' if needed.
        segmentationDisplay = ["derivative-based(default)", "sliding-window", "matlab", "uniform"];
        segmentationFcns = {@dySegmentation, @swSegmentation, @matlabSegmentation, @uniformSegmentation};
        segmentationChoice = "derivative-based(default)";  % Default option
        segmentationFcn = @dySegmentation;
        uniformPercentage = 10;     % Default when "uniform" segmentation is selected
        swMetric = 'mean';          % Default metric for sliding-window segmentation
    
        % ---- Time Window Settings ----
        % Different time windows can be chosen in 'Run options'
        % window. Default time window and default values for
        % 'startTime' and 'andTime' are set below and can be
        % changed in 'Run options' if needed.
        timeWindowDisplay = ["Fault-trigger-based(default)", "fixed time", "fault detection"];
        timeWindowChoice = "Fault-trigger-based(default)";   % Default option
        startTime = 0;                        % Used only in "fixed time" mode
        endTime = 10;                         % Used only in "fixed time" mode
        windowLength = 25;                    % Used only in "auto" mode
    
        % ---- Effect Type Names ----
        % Effects that can be currently checked
        effectsNames = {'Exponential Divergence', 'Damped Oscillations', ...
                        'Permanent Oscillations', 'Divergent Oscillations', ...
                        'Sum of Sinusoids', 'Runge Trend', ...
                        'Arctangent Trend', 'Cubic Radix Trend', 'Fifth Radix Trend'};

        % ---- Table & Signal Info ----
        lenTable             % Number of rows in results table
        colTable             % Number of columns in results table
        numLogSignals        % Number of logged signals in the model
    
        % ---- Tuning Settings ----
        % Initialization of variables used when the user enters in the fault
        % tuning mode, so the value of the selected fault parameter is
        % automatically changed.
        faultParamSelection = struct();   % Selected parameters for tuning
        tuningResults                     % Results from parameter tuning
    end


    %% === Helper Methods ===
    % This methods are useful for different purposes and applied in the callbacks;
    % 1. logMessage(app,msg) prints messages in log console during the
    %    analysis like execution status or errors.
    % 2. updateDisplayedRuns(app) updates the content of the results window
    %    by cleaning preovious results and showing signals (with segmentation) 
    %    selected by the user. Some local functions are defined and used to
    %    reduce code complexity.
    % 3. getTuningValues(~, paramName, blockType) returns a set of values that are 
    %    used for fault tuning: eg. if paramName of the considered block of the model
    %    is 'gain' than the functions return a suitable set of values from
    %    0.1 to 10 so the value of the parameter can be update through the
    %    analysis and perform different simulation to capture different
    %    behavoiurs of the system related to that block.
    % 4. confirmEffect(app, dialog) is used when the users add a new effect
    %    to evaluate. This effect is added to the faults/effects table in
    %    the first window so it can be selected and checked in the
    %    analysis. The custom function entered by the user is automatically converted
    %    into a MATLAB-compatible anonymous function handle of the form:
    %    @(p, t) <expression>, where each symbolic parameter (e.g. A, B, C, ...)
    %    is replaced by its corresponding element in the parameter vector p(i).
    %    This allows the function to be directly used with optimization tools
    %    such as lsqcurvefit.
    % 5. activateFault(app, faultName) activates a fault selected by the user in 
    %    faults/effects table. It modifies the faultInfo.xml associated to the model
    %    which path is stored in app.faultInfoXML.
    % 6. deactivateFault (app, faultName) deactivates a fault deselected by the user in 
    %    faults/effects table. It modifies the faultInfo.xml associated to the model
    %    which path is stored in app.faultInfoXML.


    % ----- Private Methods -----
    methods (Access = private)

        % 1. logMessage: Append a timestamped message to the GUI log area.
        function logMessage(app, msg)
            % Retrieve current log
            current = app.logArea.Value;
            % Create formatted timestamped message
            timestamp = string(datetime('now', 'Format', 'HH:mm:ss'));
            newLine = sprintf("[%s] %s", timestamp, msg);    
            % Append new line to log
            app.logArea.Value = [current; newLine];
        end
    
        
        % 2. updateDisplayedRuns: Refresh the plot based on selected runs/signals.
        function updateDisplayedRuns(app)
            % Extract table data and identify selected rows
            tableData = app.resultTable.Data;
            col = tableData(:, 3);
            showFlags = cellfun(@(x) islogical(x) && x, col);
            hasSelection = any(showFlags);    
            % Reset plot area
            cla(app.resultAxes, 'reset');
            hold(app.resultAxes, 'on');    
            % Handle no selection
            if ~hasSelection
                title(app.resultAxes, 'No Signal Selected');
                xlabel(app.resultAxes, '');
                ylabel(app.resultAxes, '');
                grid(app.resultAxes, 'on');
                return;
            end  
            % Iterate through all runs and plot selected signals
            nRuns = numel(app.tuningResults);
            row = 0;    
            for r = 1:nRuns
                res = app.tuningResults(r);            
                % Skip header or non-signal row
                row = row + 1;        
                % Loop through each logged signal
                for s = 1:app.numLogSignals
                    row = row + 1;          
                    if ~showFlags(row)
                        continue;  % Skip not selected rows
                    end          
                    % Plot the signal
                    plotSignal(app, res, r, s);     
                    % Mark change points
                    markChangePoints(app, res, s);
                end
            end                       
            % Finalize plot formatting
            legend(app.resultAxes, 'show', 'Location', 'best');
            xlabel(app.resultAxes, 'Time [s]');
            ylabel(app.resultAxes, 'Δ Signal');
            title(app.resultAxes, 'Selected Signals');
            grid(app.resultAxes, 'on');
            hold(app.resultAxes, 'off');

            % ---> Local functions <----   
            % Plot signal
            function plotSignal(app, res, runIndex, sigIndex)
                plot(app.resultAxes, res.Time{sigIndex}, res.YDiff{sigIndex}, 'LineWidth', 1.8, ...
                    'DisplayName', sprintf('Run %d: %s', runIndex, res.sigNames{sigIndex}));
            end
            % Mark segmentations
            function markChangePoints(app, res, sigIndex)
                if isfield(res, 'changePts') && numel(res.changePts) >= sigIndex && ~isempty(res.changePts{sigIndex})
                    for cp = 1:length(res.changePts{sigIndex})
                        try
                            xline(app.resultAxes, res.Time{sigIndex}(res.changePts{sigIndex}(cp)), '--r', ...
                                'HandleVisibility', 'off', LineWidth=2.0);
                        catch
                            % Ignore invalid indices
                        end
                    end
                end
            end 
        end
    
        
        % 3. getTuningValues: Return a vector of numeric values to sweep
        % for a specific parameter and Simulink block type.
        function vals = getTuningValues(~, paramName, blockType)   
            paramNameLower = lower(paramName);
            % switch different typed of blocks and related parameters
            switch lower(blockType)    
                case 'gain'
                    % If the parameter is 'Gain', apply multiplicative scaling
                    if contains(paramNameLower, 'gain')
                        vals = linspace(0.1, 5, 10);
                    else
                        vals = [];
                    end    
                case {'bandlimitedwhitenoise', 'randomnumber', 'whitenoise'}
                    % Handle noise-related blocks: sweep noise level or mean
                    if contains(paramNameLower, {'noise', 'variance', 'power'})
                        vals = [1e-6, 1e-5, 1e-4, 1e-3, 1e-2];
                    elseif contains(paramNameLower, 'mean')
                        vals = linspace(-1, 1, 5);
                    else
                        vals = [];
                    end   
                case 'bias'
                    % Sweep a range of bias values
                    vals = linspace(-5, 5, 9); 
                otherwise
                    % Default fallback: generic small range
                    vals = linspace(0, 1, 5);
            end
        end


        % 4. confirmEffect: add user custom effects to the table
        function confirmEffect(app, dialog)
            % Retrieve data from dialog
            effectName = strtrim(dialog.UserData.effectField.Value);
            funcExpr   = strtrim(dialog.UserData.funcField.Value);
            paramValue = strtrim(dialog.UserData.paramField.Value);
            delete(dialog);        
            % Validate effect name
            if isempty(effectName)
                uialert(app.UIFigure, 'Effect name cannot be empty.', 'Error');
                return;
            end  
            existingEffects = app.UITable.Data(:, 3);
            if any(strcmp(existingEffects, effectName))
                uialert(app.UIFigure, 'An effect with this name already exists.', 'Error');
                return;
            end
            % Validate parameter initialization
            try
                paramArray = str2num(paramValue); %#ok<ST2NM>
                if isempty(paramArray) || ~isnumeric(paramArray)
                    logMessage(app, 'Invalid initialization parameters.');
                end
                nParams = numel(paramArray);
            catch
                uialert(app.UIFigure, ...
                    'Parameters must be a numeric array, e.g. [1, 0.5, 0, 0].', ...
                    'Error');
                return;
            end 
            % Validate function expression syntax
            pattern = 'A':'Z';
            paramCount = 0;
            for i = 1:numel(pattern)
                sym = pattern(i);
                if contains(funcExpr, sym)
                    paramCount = paramCount + 1;
                    funcExpr = regexprep(funcExpr, ['\<' sym '\>'], sprintf('p(%d)', paramCount));
                end
            end        
            % Check consistency with parameter vector length
            if paramCount ~= nParams
                uialert(app.UIFigure, sprintf( ...
                    'Parameter count mismatch: found %d symbols in function, but %d values provided.', ...
                    paramCount, nParams), 'Error');
                return;
            end  
            % Create function handle string
            modelStr = sprintf('@(p,t) %s', funcExpr);
            try
                model = str2func(modelStr);
                % Try evaluating at some test point
                tTest = linspace(0, 2*pi, 5);
                yTest = model(paramArray, tTest); %#ok<NASGU>
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Invalid function handle:\n%s\n\n%s', modelStr, ME.message), ...
                    'Error');
                return;
            end
            % Prepare to insert new effect rows
            currentData = app.UITable.Data;
            newData = currentData;        
            % Fault rows are those where column 1 (fault name) is not empty
            faultIndices = find(~cellfun(@isempty, currentData(:, 1)));        
            % Insert the new effect as last subrow of each fault blocks
            for i = numel(faultIndices):-1:1
                faultRow = faultIndices(i);            
                % Find where this fault’s block ends
                if i < numel(faultIndices)
                    nextFault = faultIndices(i + 1);
                else
                    nextFault = size(newData, 1) + 1;
                end            
                % Search for last non-empty effect row inside this block
                blockRange = faultRow+1 : nextFault-1;          
                % Find the last subrow that has an effect name (column 3)
                hasEffect = find(~cellfun(@isempty, newData(blockRange, 3)), 1, 'last');            
                if isempty(hasEffect)
                    % No effects yet → insert right after the fault row
                    insertPos = faultRow + 1;
                else
                    % Insert after the last existing effect
                    insertPos = blockRange(hasEffect) + 1;
                end            
                % New effect row
                newRow = { '', '', effectName, false, '' };           
                % Insert into table
                newData = [newData(1:insertPos-1, :); newRow; newData(insertPos:end, :)];
            end
            % Update the table
            app.UITable.Data = newData;        
            % Log confirmation message
            logMessage(app,'New effect correctly added.');
            app.isNewEffect = true;
        end


        % 5. activateFault activates a fault when it is selected.
        function activateFault(app, faultName)  
            xmlFile = app.faultInfoXML;
            % Read XML
            xDoc = xmlread(xmlFile);
            % Filter containers in document
            containers = xDoc.getElementsByTagName('faultContainers');
            found = false;
            % Iterate over containers to find the one that contains
            % faultName
            for i = 0:containers.getLength-1
                container = containers.item(i);
                faults = container.getElementsByTagName('faults');
                % Iterate over faults
                for j = 0:faults.getLength-1
                    f = faults.item(j);
                    nameNode = f.getElementsByTagName('faultName').item(0);
                    if isempty(nameNode)
                        continue
                    end
                    name = char(nameNode.getFirstChild.getData);
                    if strcmp(name, faultName)
                        % Activate the container if it is not enabled
                        enabledNode = container.getElementsByTagName('isEnabled').item(0);
                        if ~isempty(enabledNode)
                            enabledValue = char(enabledNode.getFirstChild.getData);
                            if strcmpi(enabledValue, 'false')
                                enabledNode.getFirstChild.setData('true');
                            end
                        end
                        % Find fault uuid
                        uuid = char(f.getAttribute('uuid'));
                        % Update <activeFault> node
                        activeNode = container.getElementsByTagName('activeFault').item(0);
                        activeNode.setAttribute('uuid', uuid);
                        found = true;
                        break;
                    end
                end
                if found
                    break;
                end
            end  
            if ~found
                warning('Fault "%s" not found.', faultName);
            else
                % Save udpate XML
                xmlwrite(xmlFile, xDoc);
            end
        end

        % 5. deactivateFault deactivates whole faults container.
        function deactivateFault(app, faultName)
            % Read faultInfo.xml
            xDoc = xmlread(app.faultInfoXML);
            % Get all <faultContainers>
            containers = xDoc.getElementsByTagName('faultContainers');
            found = false;
        
            % Iterate over containers
            for i = 0:containers.getLength-1
                container = containers.item(i);
                faults = container.getElementsByTagName('faults');
                % Find faultName in containers
                for j = 0:faults.getLength-1
                    f = faults.item(j);
                    nameNode = f.getElementsByTagName('faultName').item(0);
                    if isempty(nameNode)
                        continue
                    end
                    name = char(nameNode.getFirstChild.getData);
                    % container found
                    if strcmp(name, faultName)
                        % update isEnabled node
                        isEnabledNodes = container.getElementsByTagName('isEnabled');
                        if isEnabledNodes.getLength == 0
                            newNode = xDoc.createElement('isEnabled');
                            newNode.appendChild(xDoc.createTextNode('false'));
                            container.appendChild(newNode);
                        else
                            % If node still exists, se to 'false'
                            node = isEnabledNodes.item(0);
                            if node.hasChildNodes
                                node.getFirstChild.setData('false');
                            else
                                node.appendChild(xDoc.createTextNode('false'));
                            end
                        end
                        found = true;
                        logMessage(app, sprintf('Fault "%s" deactivated.\n', faultName));
                        break;
                    end
                end      
                if found
                    break;
                end
            end        
            % Save updates or trigger error
            if ~found
                logMessage(app, 'Fault "%s" not found.\n' + faultName);
            else
                xmlwrite(app.faultInfoXML, xDoc);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % The initial page is designed to guide new
            % users in the use of the application through simple
            % instructions and links. Other components are initially hide 
            % and set visible later.
            % Suggestions are also available in the log area.

            % Hide unnecessary components in the initial window
            componentsToHide = { ...
                app.pauseButton, app.continueButton, app.componentDropDown, ...
                app.instructionLabel, app.OKButton, app.UITable, ...
                app.addEffectButton, app.runOptionsButton, app.faultTuningButton, ...
                app.openModelCheckBox ...
            };
            for k = 1:numel(componentsToHide)
                componentsToHide{k}.Visible = 'off';
            end
            
            % Components to show
            componentsToShow = {app.InstructionPanel};
            for k = 1:numel(componentsToShow)
                componentsToShow{k}.Visible = 'on';
            end
            
            % Log initialization message
            logMessage(app, 'Ready. Load a model to start the simulation.');
        end

        % Button pushed function: loadModelButton
        function loadModelButtonPushed(app, event)
            % When the loadModel button is pushed, the user can choose a
            % simulink model (extension .slx) to analyze with the tool. 
            % Then, useful informations about the model are saved in app properties 
            % like the model path, the model name and the associated fault models. 
            % If no fault model is found, a message is logged.
            % Finally, the window is updated to show the instructions related to the
            % choice of a component of the model: it is useful to analyze
            % the robustness of a single part of the system to external
            % changes
                        
            % --- Hide unnecessary components and show instructions panel ---.
            % Hide unnecessary components
            componentsToHide = { ...
                app.addEffectButton, app.runOptionsButton, app.faultTuningButton, app.UITable ...
            };             
            for k = 1:numel(componentsToHide)
                componentsToHide{k}.Visible = 'off';  
            end
            % Show the instructions panel
            app.InstructionPanel.Visible = 'on';  


            % --- Prompt user to select a Simulink model ---
            [file, path] = uigetfile({'*.slx;*.mdl', 'Simulink Models (*.slx, *.mdl)'}, ...
                                     'Select a Simulink Model');           
            % If user cancels selection, exit the function
            if file == 0
                return;
            end          
            % Save full path to model and add the directory to MATLAB path
            app.modelPath = fullfile(path, file); 
            addpath(path);          
            % Extract the model name from the file path
            [~, app.modelName, ~] = fileparts(app.modelPath);        
            % Load the model into memory if it's not already loaded
            if ~bdIsLoaded(app.modelName)
                load_system(app.modelName);
            end  
            % Minimize or hide the model window to keep UI clean 
            if bdIsLoaded(app.modelName)
                try
                    set_param(app.modelName, 'Open', 'off'); 
                catch ME
                    logMessage(app, "Closing model window not allowed '%s': %s" + app.modelName + ME.message);
                end
            end
            

            % --- Find faults in the model ---
            app.faultsList = Simulink.fault.findFaults(app.modelName);       
            app.faultModel = Simulink.fault.getFaultModels(app.modelName);    
            app.faultInfoXML = app.modelName + "_faultInfo.xml";              
            % Load model
            load_system(app.faultModel);                                                 
            % Handle case where no faults are found
            if isempty(app.faultsList)
                logMessage(app, "No fault found.");  
                %app.UITable.Data = {};               
                return;                              
            end
       

            % --- Update UI for component selection ---
            app.loadModelToStartButton.Visible = 'off';                    
            app.instructionLabel.Text = 'Select a component to continue.'; 
            app.instructionLabel.Visible = 'on';                           
            app.openModelCheckBox.Visible = 'on';                         
            app.componentDropDown.Visible = 'on';                          
            app.OKButton.Visible = 'on';
            
            % Populate the dropdown with unique components from the fault list
            componentList = unique({app.faultsList.ModelElement});
            app.componentDropDown.Items = componentList;

        end

        % Button pushed function: addEffectButton
        function addEffectButtonPushed(app, event)
            % This callback opens a modal UI window that allows the user to define
            % a new effect. The user can input:
            %     1. The name of the effect
            %     2. A parametric function in t (as a string)
            %     3. Initialization parameters [x, y, z]
            %
            % When confirmed, the function `confirmEffect(app, d)` is called to handle
            % validation, saving, or processing of the input data

            % --- Create the main dialog window ---
            d = uifigure('Name', 'Add new effect...', ...
                         'Position', [450 450 320 240], ...
                         'Resize', 'off');
        
            % --- Field 1: Effect name ---
            uilabel(d, 'Text', 'Effect Name:', ...
                       'Position', [20 190 100 22], ...
                       'HorizontalAlignment', 'left');
            effectField = uieditfield(d, 'text', ...
                       'Position', [130 190 160 22]);
        
           
            % --- Field 2: Parametric function f(t) ---
            uilabel(d, 'Text', 'Function f(t):', ...
                       'Position', [20 145 100 22], ...
                       'HorizontalAlignment', 'left');
            funcField = uieditfield(d, 'text', ...
                       'Position', [130 145 160 22], ...
                       'Value', 'e.g. [A*cos(B*t + C) + D]', ...
                       'FontColor', [0.5 0.5 0.5]);   % Gray placeholder text
        
            % Remove the placeholder when the user starts typing or changes the field
            funcField.ValueChangedFcn = @(src, event) clearPlaceholder(src);
            funcField.ValueChangingFcn = @(src, event) clearPlaceholder(src);
        

            % --- Field 3: Initialization parameters ---
            uilabel(d, 'Text', 'Parameters [x, y, z]:', ...
                       'Position', [20 100 120 22], ...
                       'HorizontalAlignment', 'left');
            paramField = uieditfield(d, 'text', ...
                       'Position', [150 100 140 22]);
       
           
            % --- Confirm button ---
            uibutton(d, 'Text', 'Add', ...
                        'Position', [110 40 100 30], ...
                        'ButtonPushedFcn', @(btn, event) confirmEffect(app, d));
        

            % --- Store handles in the figure's UserData ---
            d.UserData.effectField = effectField;
            d.UserData.funcField = funcField;
            d.UserData.paramField = paramField;
        
        
        % ---> Helper function: <---
        % clearPlaceholder removes the gray example text when the user starts editing the field
        % and switches the text color back to black.
        function clearPlaceholder(editField)
            if strcmp(editField.Value, 'e.g. [A*cos(B*t + C) + D]')
                editField.Value = '';
                editField.FontColor = [0 0 0];
            end
        end

            
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            % Reset plot area
            cla(app.resultAxes, 'reset');
            hold(app.resultAxes, 'on'); 

            % Clean previous results in faults/effects table 
            for r = 1:app.lenTable
                app.UITable.Data{r,5} = '';   
            end
  
            % Set simulation buttons 
            app.pauseButton.Visible = 'on';
            app.runButton.Visible = 'off';

            % If no model is selected, log a message and return
            if isempty(app.modelName)
                uialert(app.UIFigure, 'Load a model before starting the simulation.', 'Error');
                return;
            end
            
            % If no effect is selected, log a message and return
            col4 = app.UITable.Data(:,4);
            if ~any(cellfun(@(x) isequal(x,1) || isequal(x,true), col4))
                logMessage(app, 'Please, select an effect or add a customized one.');
                return
            end
            
            % Filter faults for selected component
            faults = app.faultsList(strcmp({app.faultsList.ModelElement}, app.componentDropDown.Value));
 
            % Filter active faults
            activefaults = 0;
            for n = 1:length(faults)
                if faults(n).IsActive
                    activefaults = activefaults + 1;
                end
            end

            % If no fault is active, log a message and reset simulation
            % buttons visibility
            if activefaults == 0
                logMessage(app, 'No active fault found. Select a fault to run the analysis.');
                app.pauseButton.Visible = 'off';
                app.runButton.Visible = 'on';
                return;
            end

            % Simulation started
            logMessage(app, 'Starting simulations...');

            % ------------- Tuning & simulation -------------
            % Prepare results container
            app.tuningResults = []; % clear previous results, store as array of structs
                    

            for i = 1:length(faults)
                % skip inactive faults
                if ~faults(i).IsActive
                    continue;
                end
                
                f = faults(i);
                faultName = f.Name;


                % ----------------- Tuning Parameter Handling -----------------
                blkPath = '';
                paramName = '';
                hasTuning = false;
                origValStr = ''; % default in case user does not select parameter

                if isfield(app.faultParamSelection, faultName) && ...
                        ~isempty(app.faultParamSelection.(faultName).Choice)
                    blkPath   = app.faultParamSelection.(faultName).Choice.BlockPath;
                    paramName = app.faultParamSelection.(faultName).Choice.ParamName;

                    try
                        origValStr = get_param(blkPath, paramName);
                    catch ME
                        logMessage(app, sprintf('[ERROR] Could not read original value for %s.%s: %s', blkPath, paramName, ME.message));
                        continue;
                    end

                    % determine tuning values
                    blkType = '';
                    try blkType = get_param(blkPath, 'BlockType'); catch, end
                    tuningValues = getTuningValues(app, paramName, blkType);

                    if ~isempty(tuningValues)
                        hasTuning = true;
                    else
                        logMessage(app, sprintf('No tuning values found for %s.%s — running base analysis.', blkPath, paramName));
                    end
                else
                    blkType = '';
                    % If no parameter selected, attempt to read original value only if path and name exist
                    if ~isempty(blkPath) && ~isempty(paramName)
                        try
                            origValStr = get_param(blkPath, paramName);
                        catch
                            origValStr = '';
                        end
                    end
                    logMessage(app, sprintf('No tuning parameter selected for fault: %s — running base analysis.', faultName));
                    tuningValues = getTuningValues(app, paramName, blkType);
                end

                % If no tuning parameter or no tuning values found, use original value
                if ~hasTuning
                    if ~isempty(origValStr)
                        tuningValues = {origValStr};
                    else
                        tuningValues = {[]}; % fallback: run simulation without changing any parameter
                    end
                end

                % ----------------- End of Tuning Parameter Handling -----------------
                nAnalysis = 0;

                % iterate over tuning values
                for vi = 1:length(tuningValues)
                    nAnalysis = nAnalysis + 1;
                    if iscell(tuningValues)
                        val = tuningValues{vi};
                    else
                        val = tuningValues(vi);
                    end


                    % Set_param only if selected
                    if hasTuning
                        try
                            set_param(blkPath, paramName, num2str(val));
                        catch ME
                            logMessage(app, sprintf('[ERROR] Could not set %s.%s = %s : %s', blkPath, paramName, num2str(val), ME.message));
                            continue;
                        end
                    end

                    % --- run paired simulations: fault OFF then ON ---
                    try
                        % Fault OFF
                        Simulink.fault.enable(f.ModelElement, false);
                        simIn1 = Simulink.SimulationInput(app.modelName);
                        if app.timeWindowChoice == "Fault-trigger-based(default)"
                            simIn1 = simIn1.setModelParameter('StopTime', num2str(app.windowLength));
                        else
                            simIn1 = simIn1.setModelParameter('StopTime', num2str(app.endTime));
                        end
                        simOut1 = sim(simIn1);

                        % Fault ON
                        if f.TriggerType == "Conditional" && f.Conditional.LogActivity == false
                            f.Conditional.LogActivity = true;
                        end
                        Simulink.fault.enable(f.ModelElement, true);
                        simIn2 = Simulink.SimulationInput(app.modelName);
                        if app.timeWindowChoice == "Fault-trigger-based(default)"
                            simIn2 = simIn2.setModelParameter('StopTime', num2str(app.windowLength));
                        else    
                            simIn2 = simIn2.setModelParameter('StopTime', num2str(app.endTime));
                        end
                        simOut2 = sim(simIn2);

                        % --- compute diff for all logged signals ---
                        logs1 = simOut1.logsout;
                        logs2 = simOut2.logsout;

                        nSignals = logs1.numElements;
                        t = cell(1, nSignals);
                        y1 = cell(1, nSignals);
                        y2 = cell(1, nSignals);
                        yDiff = cell(1, nSignals);
                        
                        sigNames = cell(nSignals,1);
                        for j = 1:nSignals
                            % Signal name
                            sigName = logs1{j}.Name;
                            sigNames{j} = sigName;
                            % Tima/data
                            t{j} = logs1{j}.Values.Time;
                            y1{j} = logs1{j}.Values.Data;
                            y2{j} = logs2{j}.Values.Data;                      
                            % Difference
                            yDiff{j} = y1{j} - y2{j};
                        end

                        % --- Time window (all logged signals) ---
                        app.numLogSignals = nSignals;
                        tWin  = cell(1, nSignals);
                        yWin  = cell(1, nSignals);

                        for k = 1:nSignals
                           
                            tk = t{k};
                            yk = yDiff{k};

                            if app.timeWindowChoice == "fixed time"
                                idxStart = find(tk >= app.startTime, 1, 'first');
                                idxEnd   = find(tk <= app.endTime, 1, 'last');

                            elseif app.timeWindowChoice == "fault detection"
                                yk = y1{k};
                                % Adjustable parameters
                                threshold = 0.05;       % Threshold for detecting a sudden change
                                windowRestart = 20;     % Number of consecutive samples required to confirm return to normal
                                
                                % Compute the first derivative (difference between consecutive samples)
                                dy = diff(yk);
                                
                                % --- 1. Detect the first abrupt change in the signal ---
                                % Find the first point where the derivative exceeds the threshold
                                idxStartCandidate = find(abs(dy) > threshold, 1, 'first');
                                
                                if isempty(idxStartCandidate)
                                    % No abrupt change detected
                                    idxStart = 1;
                                else
                                    idxStart = idxStartCandidate;
                                end
                                
                                % --- 2. Detect when the signal returns to normal behavior ---
                                idxEnd = length(yk); % Default (if no end detected)
                                
                                if ~isempty(idxStartCandidate)
                                    % Look at the derivative only after the fault start
                                    dyAfter = abs(dy(idxStart:end)) < threshold;
                                
                                    % Use convolution to find a continuous sequence of samples below threshold
                                    convRes = conv(double(dyAfter), ones(1, windowRestart), 'same');
                                
                                    % First index where the convolution equals the window length
                                    idxEndCandidate = find(convRes == windowRestart, 1, 'first');
                                
                                    if ~isempty(idxEndCandidate)
                                        % Convert local index to global index in the original signal
                                        idxEnd = idxStart + idxEndCandidate - 1;
                                    end
                                end
                                
                            elseif app.timeWindowChoice == "Fault-trigger-based(default)"
                                if faults(i).TriggerType == "Always On"
                                    idxStart = 1;
                                    idxEnd = find(tk == app.windowLength, 1, 'first');

                                elseif faults(i).TriggerType == "Timed"
                                    idxStart = find(tk == faults(i).StartTime, 1, 'first');
                                    idxEnd   = find(tk == app.windowLength, 1, 'first'); 

                                elseif faults(i).TriggerType == "Conditional"
                                    % --- Get Conditional signal to find start time ---
                                    runID = Simulink.sdi.getCurrentSimulationRun(app.modelName);
                                    signals = runID.getAllSignals;
                                    cond_signal = [];

                                    for j = 1:length(signals)
                                        if strcmp('Conditionals', signals(j).Domain)
                                            cond_signal = signals(j);
                                            break;
                                        end
                                    end

                                    if isempty(cond_signal)
                                        logMessage(app, 'Warning: Conditional signal not found.');
                                        continue;
                                    end

                                    idxFirst = find(cond_signal.Values.Data == 1, 1, 'first');

                                    if isempty(idxFirst)
                                        logMessage(app, 'Warning: Fault Condition is never verified during simulation.');
                                        continue;
                                    end

                                    tFirst = cond_signal.Values.Time(idxFirst);
                                    tLast  = find(tk == app.windowLength, 1, 'first');

                                    idxStart = find(tk >= tFirst, 1, 'first');
                                    idxEnd   = find(tk <= tLast, 1, 'last');
                                else
                                    idxStart = 1;
                                    idxEnd   = length(yk);
                                end
                            end

                            % No indexes founded
                            if isempty(idxStart), idxStart = 1; end
                            if isempty(idxEnd), idxEnd = length(yk); end

                            % Save time window
                            yWin{k} = yk(idxStart:idxEnd);
                            tWin{k} = tk(idxStart:idxEnd);
                        end

                    catch ME
                        logMessage(app, ['[ERROR] Simulation failed: ' ME.message]);
                        continue;
                    end

                    % --- segmentation & results assembly ---

                    chosenSegmentation = app.segmentationFcn;

                    % Initialize containers for all signals
                    changePtsAll = cell(1, app.numLogSignals);
                    segmentsAll  = cell(1, app.numLogSignals);
                    hasEffect = false(app.numLogSignals,1);

                    for s = 1:app.numLogSignals
                        % Get current signal window
                        yw = yWin{s};

                        % --- Compute change points for this signal ---
                        if strcmp(chosenSegmentation, 'uniform')
                            opt = uniformSegmentationOptions();
                            opt.perc = app.uniformPercentage;
                            changePts = chosenSegmentation(yw, opt);
                        elseif strcmp(chosenSegmentation, 'sliding-window')
                            opt = swSegmentationOptions;
                            opt.metric = app.swMetric;
                            changePts = chosenSegmentation(yw, opt);
                        else
                            changePts = chosenSegmentation(yw);
                        end

                        % --- Create segments based on change points ---
                        segments = cell(length(changePts), 1);
                        segments{1} = yw(1:changePts(1)-1);
                        for k = 1:length(changePts)-1
                            segments{k+1} = yw(changePts(k):changePts(k+1)-1);
                        end

                        % Store segmentation results for this signal
                        changePtsAll{s} = changePts;
                        segmentsAll{s}  = segments;

                        % === 3. Analysis ===
                        N = length(segments);
                        effects = cell(N, 2);
                        % row corresponding to current fault
                        
                        j = find(strcmp(faults(i).Name, app.UITable.Data(:,1)), 1);
                        
                        
                        % Find selected effects; if none is selectede, then
                        % return
                        if ~isempty(j)
                            effectsSelectedIdx = find(cell2mat(app.UITable.Data(j+1 : j+length(app.effectsNames), 4)));
                            if isempty(effectsSelectedIdx)
                                return;
                            end
                            
                            for k = 1:N 
                                seg = segments{k};

                                for mRel = effectsSelectedIdx'   
                                    mIdx = j + mRel;             
                                    
                                    
                                    effectName = app.effectsNames{mRel};
                                    clean_seg = cleanSignal(seg);
                                    [isDiv, howDiv] = isDivergent(clean_seg, effectName);
                            
                                    
                                    effects{k,1} = isDiv;
                                    effects{k,2} = [effects{k,2} howDiv];
                            
                                    if isDiv
                                        style = uistyle('HorizontalAlignment','center');
                                        addStyle(app.UITable, style, 'cell', [mIdx, 5]);  
                                        app.UITable.Data{mIdx,5} = '🟢';
                                        hasEffect(s) = true;
                                    end
                                end
                            end                                           
                        end
                        
                    end

                    % --- Build results structure ---
                    res = struct();
                    res.FaultName        = faultName;
                    res.BlockPath        = blkPath;
                    res.ParamName        = paramName;
                    res.ParamValue       = val;
                    res.Time             = t;           % cell array of time vectors
                    res.YDiff            = yDiff;       % cell array of signal differences
                    res.sigNames         = sigNames;
                    res.SimOutFaultOff   = simOut1;
                    res.SimOutFaultOn    = simOut2;
                    res.Segmentation     = app.segmentationFcn;
                    res.changePts        = changePtsAll;
                    res.Segments         = segmentsAll;
                    res.hasEffect        = hasEffect;
                    % Append results
                    app.tuningResults = [app.tuningResults; res];

                end

                % === Populate Results Table (2 columns, hierarchical style) ===
                if isempty(app.tuningResults)
                    return;
                end

                nRuns = numel(app.tuningResults); 
                % Total rows = (1 main row + numLogSignals sub-rows) * numRuns 
                nRows = (app.numLogSignals + 1) * nRuns;
                data = cell(nRows, 3);

                row = 0; % row counter

                for r = 1:nRuns
                    res = app.tuningResults(r);

                    % --- Main run row ---
                    if isempty(res.ParamValue)
                        runLabel = sprintf('Run %d: %s', r, res.FaultName);
                    else
                        runLabel = sprintf('Run %d: Fault Value = %g', r, res.ParamValue);
                    end

                    row = row + 1;
                    data{row,1} = runLabel;
                    data{row,2} = '';  
                    data{row,3} = '';  % checkbox state


                    % --- Sub-rows for each logged signal ---

                    for s = 1:app.numLogSignals
                        row = row + 1;
                        if res.hasEffect(s)
                            data{row,1} = 'Effect detected ⚠️';
                        else
                            data{row,1} = '';
                        end
                        data{row,2} = res.sigNames{s};
                        data{row,3} = false;
                    end
                end

                % --- Configure table ---
                app.resultTable.Data = data;
                app.resultTable.ColumnName = {'Run: Fault', 'Signal', 'Show'};
                app.resultTable.ColumnEditable = [false false true];
                app.resultTable.CellEditCallback = @(src, event) updateDisplayedRuns(app);

            end % faults loop

            logMessage(app, 'All simulations completed. Analysis completed.');
            app.pauseButton.Visible = 'off';
            app.runButton.Visible = 'on';
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            % When a simulaion is paused, the button visibility is turned
            % off and the 'continue' button visibility is turned on
            set_param(app.modelName, 'SimulationCommand', 'pause');
            app.pauseButton.Visible = 'off';
            app.continueButton.Visible = 'on';
        end

        % Button pushed function: continueButton
        function continueButtonPushed(app, event)
            % When 'continue' simulation button is pushed, button
            % visibility is turned off and the 'pause' button visibility is
            % turned on
            set_param(app.modelName, 'SimulationCommand', 'continue');
            app.continueButton.Visible = 'off';
            app.pauseButton.Visible = 'on';
        end

        % Button pushed function: runOptionsButton
        function runOptionsButtonPushed(app, event)
            % This section creates and manages a modal dialog that allows the user to
            % configure the execution options for the analysis. The dialog provides:
            %
            % 1) **Segmentation settings**
            %    - A dropdown menu to select the segmentation method.
            %    - Additional fields that appear dynamically depending on the chosen
            %      segmentation logic:
            %        • "uniform": enables a percentage field.
            %        • "sliding-window": enables a metric selector.
            %
            % 2) **Time-window settings**
            %    - A dropdown menu for selecting the time-window mode.
            %    - If "fixed time" is selected, Start/End numeric fields become visible.
            %
            % 3) **Action buttons**
            %    - OK     applies the options and closes the dialog
            %    - Apply  saves the selected options without closing
            %    - Cancel restores defaults and closes
            %    - Help   opens a short description popup
            %
            % 4) **Dynamic behaviour**
            %    UI elements are shown or hidden in real time based on the user's
            %    selection, controlled by nested functions:
            %        • toggleSegmentationField()
            %        • toggleFixedTimeFields()
        
                        
            % --- Available options ---
            segmentationOptions = app.segmentationDisplay;   
            timeWindowOptions = app.timeWindowDisplay;       
        
            % --- Dialog window and layout ---
            dlg = uifigure('Name', 'Run options', ...
                           'Position', [500 300 450 320]);
        
            gl = uigridlayout(dlg, [4,1]);
            gl.RowHeight = {30, 110, 90, 40};
            gl.Padding = [5 10 10 5];
        
            % --- Description ---
            uilabel(gl, ...
                'Text', ['Here you can configure the time window of interest ' ...
                '(by default, the analysis starts from the first fault activation) ' ...
                         'and the segmentation logic to apply.'], ...
                'FontWeight', 'normal', ...
                'WordWrap', 'on');
        
            
            % --- Time window row ---
            timeLayout = uigridlayout(gl, [3,2]);
            timeLayout.RowHeight = {22,15,15,15};
            timeLayout.ColumnWidth = {100, '1x'};
            uilabel(timeLayout, 'Text', 'Slicing: ', 'HorizontalAlignment', 'right', 'FontWeight', 'bold');
            timeDrop = uidropdown(timeLayout, ...
                'Items', timeWindowOptions, ...
                'Value', app.timeWindowChoice, ...
                'ValueChangedFcn', @(src,event) toggleFixedTimeFields());

            % --- Window length field (initially hidden, unless "auto" slicing selected) ---
            windowLabel = uilabel(timeLayout, 'Text', 'Duration (s):', ...
                'HorizontalAlignment', 'right', 'Visible', strcmp(app.timeWindowChoice, 'Fault-trigger-based(default)'));
            windowEdit = uieditfield(timeLayout, 'numeric', ...
                'RoundFractionalValues', true, ...
                'Value', app.windowLength, ...
                'Visible', strcmp(app.timeWindowChoice, 'Fault-trigger-based(default)'));
        
            % --- Start/End fields (initially hidden, unless fixed time selected) ---
            startLabel = uilabel(timeLayout, 'Text', 'Start time:', ...
                'HorizontalAlignment', 'right', 'Visible', strcmp(app.timeWindowChoice,'fixed time'));
            startEdit = uieditfield(timeLayout, 'numeric', ...
                'Value', app.startTime, ...
                'Visible', strcmp(app.timeWindowChoice,'fixed time'));
        
            endLabel = uilabel(timeLayout, 'Text', 'End time:', ...
                'HorizontalAlignment', 'right', 'Visible', strcmp(app.timeWindowChoice,'fixed time'));
            endEdit = uieditfield(timeLayout, 'numeric', ...
                'Value', app.endTime, ...
                'Visible', strcmp(app.timeWindowChoice,'fixed time'));
        
            % --- Segmentation row ---
            segLayout = uigridlayout(gl, [1,2]);
            segLayout.RowHeight = {22,15,15};
            segLayout.ColumnWidth = {100, '2x'};
            uilabel(segLayout, 'Text', 'Segmentation: ', 'HorizontalAlignment', 'right', 'FontWeight', 'bold');
            segDrop = uidropdown(segLayout, ...
                'Items', segmentationOptions, ...
                'Value', app.segmentationChoice, ...
                'ValueChangedFcn', @(src,event) toggleSegmentationField());

            % --- Uniform percentage field (initially hidden, unless uniform segmetation selected) ---
            uniformLabel = uilabel(segLayout, 'Text', 'Percentage:', ...
                'HorizontalAlignment', 'right', 'Visible', strcmp(app.segmentationChoice,'uniform'));
            uniformEdit = uieditfield(segLayout, 'numeric', ...
                'Limits', [0 100], ...
                'RoundFractionalValues', true, ...
                'ValueDisplayFormat', '%.0f%%', ...
                'Value', app.uniformPercentage, ...
                'Visible', strcmp(app.segmentationChoice, 'uniform'));

            % --- sliding-window metric field (initially hidden, unless swSegmentation selected) ---
            swLabel = uilabel(segLayout, 'Text', 'Metric:', ...
                'HorizontalAlignment', 'right', 'Visible', strcmp(app.segmentationChoice,'sliding-window'));
            swDrop = uidropdown(segLayout, ...
                'Items', {'mean', 'var', 'energy', 'rms'}, ...
                'Value', app.swMetric, ...
                'Visible', strcmp(app.segmentationChoice, 'swSegmentation'));


            % --- Buttons row ---
            btnLayout = uigridlayout(gl, [1,4]);
            btnLayout.ColumnWidth = {'1x','1x','1x','1x'};
            
            uibutton(btnLayout, 'Text', 'OK', ...
                'ButtonPushedFcn', @(src,event) onOK(app, dlg, segDrop, timeDrop, startEdit, endEdit));
            uibutton(btnLayout, 'Text', 'Cancel', ...
                'ButtonPushedFcn', @(src,event) onCancel(app, dlg));
            uibutton(btnLayout, 'Text', 'Help', ...
                'ButtonPushedFcn', @(src,event) onHelp(dlg));
            uibutton(btnLayout, 'Text', 'Apply', ...
                'ButtonPushedFcn', @(src,event) onApply(app, segDrop.Value, timeDrop.Value, startEdit.Value, endEdit.Value));
  
            
            % --- Nested functions ---
            function toggleSegmentationField()
                % Show/hide numeric fields depending on dropdown value
                if strcmp(segDrop.Value, 'uniform')
                    uniformLabel.Visible = 'on';
                    uniformEdit.Visible = 'on';
                else
                    uniformLabel.Visible = 'off';
                    uniformEdit.Visible = 'off';
                end

                if strcmp(segDrop.Value,'sliding-window')
                    swLabel.Visible = 'on';
                    swDrop.Visible = 'on';
                else
                    swLabel.Visible = 'off';
                    swDrop.Visible = 'off';
                end
            end

            function toggleFixedTimeFields()
                % Show/hide numeric fields depending on dropdown value
                if timeDrop.Value == "fixed time"
                    startLabel.Visible = 'on';
                    startEdit.Visible = 'on';
                    endLabel.Visible = 'on';
                    endEdit.Visible = 'on';
                else
                    startLabel.Visible = 'off';
                    startEdit.Visible = 'off';
                    endLabel.Visible = 'off';
                    endEdit.Visible = 'off';
                end

                if timeDrop.Value == "Fault-trigger-based(default)"
                    windowLabel.Visible = 'on';
                    windowEdit.Visible = 'on';
                else
                    windowLabel.Visible = 'off';
                    windowEdit.Visible = 'off';
                end
            end
        
            function onApply(app, selectedSegmentation, selectedTimeWindow, startVal, endVal)
                % --- Segmentation ---
                idx = find(app.segmentationDisplay == selectedSegmentation, 1);
                if ~isempty(idx)
                    app.segmentationChoice = selectedSegmentation;
                    app.segmentationFcn = app.segmentationFcns{idx};
                else
                    warning('Selected segmentation not recognized');
                end
        
                % --- Time window ---
                app.timeWindowChoice = selectedTimeWindow;
                if selectedTimeWindow == "fixed time"
                    app.startTime = startVal;
                    app.endTime = endVal;
                else
                    % reset to defaults if not fixed
                    app.startTime = 0;
                    app.endTime = 10;
                end
      
            end
        
            function onOK(app, dlg, segDrop, timeDrop, startEdit, endEdit)
                % Apply before closing
                onApply(app, segDrop.Value, timeDrop.Value, startEdit.Value, endEdit.Value);
                delete(dlg);
            end
        
            function onCancel(app, dlg)
                % Reset to defaults
                app.segmentationChoice = app.segmentationDisplay(1);
                app.segmentationFcn = app.segmentationFcns{1};
                app.timeWindowChoice = "Fault-trigger-based(default)";
                app.startTime = 0;
                app.endTime = 10;
                delete(dlg);
            end
        
            function onHelp(dlg)
                uialert(dlg, ['Here you can choose:' newline ...
                              '- the segmentation logic' newline ...
                              '- the slicing mode (Fault-trigger-based, fixed, or fault detection).'], ...
                              'Help');
            end

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % When a checkbox of the second column is selected, the 
            % corresponding fault is activate by using the 'activateFault'
            % function defined in the helper functions
             
            % The selection event is at row and col:
            row = event.Indices(1);
            col = event.Indices(2);
            % New value for selected checkbox
            newValue = event.NewData;
        
            % Fault name (row = row, first column)
            faultName = app.UITable.Data{row, 1};
        
            % If the selection is in the second column, update fault
            % activation state
            if col == 2
                if newValue
                    % Activate fault if checkbox new value = true
                    try
                        activateFault(app, faultName);
                        
                    catch ME
                        logMessage(app, 'Error during fault activation "%s": %s');
                    end
                    % set other checkbox fault in the same container to false
                    % (Simulink Fault Analyzer automatically switch
                    % them off)
                    for j = 1:length(app.effectsNames)+1:app.lenTable-1
                        if j ~= row
                            app.UITable.Data{j, 2} = false;
                        end
                    end
                else
                    % Deactivate fault if checkbox new value = false
                    % Only one fault type can be selected for each fault,
                    % so if none is selected the fault is turned off.
                    try
                        deactivateFault(app, faultName);
                    catch
                        logMessage(app, 'Error during fault deactivation "%s": %s');
                    end
                end
                % Reaload system after changes
                bdclose(app.modelName);
                load_system(app.modelName);
                if app.openModelCheckBox.Value
                   open_system(app.modelPath);
                end
                app.faultsList = Simulink.fault.findFaults(app.modelName);
            end


        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % When a simulation is stopped, the 'run' button is set as
            % visible and a message is logged
            set_param(app.modelName, 'SimulationCommand', 'stop');
            app.runButton.Visible = 'on';
            logMessage(app, 'Simulation stopped');
        end

        % Button pushed function: saveOutputButton
        function saveOutputButtonPushed(app, event)
            % Fault table can be stored in a .mat file at the end of the
            % analysis
            
            % Store current table
            dataToSave = app.UITable.Data;  
            % Suggest a suitable name (e.g. 'outputTable_2025-11-15');
            suggestedName = sprintf('outputTable_%s.mat', datetime('now', 'Format', 'yyyy-MM-dd'));            
            [file, path] = uiputfile('*.mat', 'Save output as', suggestedName);

            % Return if no name is chosen.
            if isequal(file, 0)
                return;  
            end

            % Build full path
            fullFileName = fullfile(path, file);
            save(fullFileName, 'dataToSave');
        end

        % Value changed function: componentDropDown
        function componentDropDownValueChanged(app, event)
            % set selected component as app property
            app.component = app.componentDropDown.Value;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Once OK button is pushed, the instruction panel is
            % substituted by the faults/effects table. If the 'open model'
            % checkbox is selected, the model .slx is opened too (already
            % loaded in 'loadModelButtonPushed' callback).
            % The table is created by adding faults of the selected
            % component and for each fault it is possible to choose from a
            % set of effects added as subrows.
            
            % Hide instruction panel 
            app.InstructionPanel.Visible = 'off';

            % Show necessary components
            app.UITable.Visible = 'on';
            app.addEffectButton.Visible = "on";
            app.runOptionsButton.Visible = "on";
            %app.faultTuningButton.Visible = "on";

            % If 'open model' is selected, open the model too
            if app.openModelCheckBox.Value
                open_system(app.modelPath)
            end

            % --- Create table ---
            % Filter faults for selected component
            faults = app.faultsList(strcmp({app.faultsList.ModelElement}, app.componentDropDown.Value));
            % Initialize table dimension to avoid dynamic changes
            tableData = cell(1000,5);
            
            rowIdx = 1;
            for i = 1:length(faults)
                faultName = faults(i).Name;
                isActive = faults(i).IsActive;
                % Principal row for fault data
                tableData{rowIdx, 1} = faultName;
                tableData{rowIdx, 2} = isActive;
                tableData{rowIdx, 4} = '';

                rowIdx = rowIdx + 1;
                % Sub-rows for effects analysis
                for j = 1:length(app.effectsNames)
                    tableData{rowIdx, 1} = '';
                    tableData{rowIdx, 2} = '';
                    tableData{rowIdx, 3} = app.effectsNames{j};
                    tableData{rowIdx, 4} = false;
                    tableData{rowIdx, 5} = '';

                    rowIdx = rowIdx + 1;
                end
            end
            
            %store true table dimensions in app properties
            app.lenTable = rowIdx;
            app.colTable = 5;
    
            % fill faults/effects table and set editability
            app.UITable.Data = tableData;
            app.UITable.ColumnEditable = [false, true, false, true, false];
            

        end

        % Callback function: whatIsSfera
        function whatIsSferaClicked(app, event)
            % Brief dialogue window that explain tool functionalities
            uialert(app.UIFigure, ...
                ['SFERA (Smart Faults Effects and Robustness Analyzer) is a MATLAB-based tool ' ...
                 'for automatic detection and analysis of fault effects in Simulink component models.'], ...
                'About SFERA');
        end

        % Button pushed function: loadModelToStartButton
        function loadModelToStartButtonPushed(app, event)
            % Same callback for the first load and the others
            loadModelButtonPushed(app);
        end

        % Cell edit callback: resultTable
        function resultTableCellEdit(app, event)
            % Modify plot in the second window when signals are
            % selected/deselected
            updateDisplayedRuns(app);
        end

        % Button pushed function: faultTuningButton
        function faultTuningButtonPushed(app, event)
            if isempty(app.modelPath)
                return;
            end

            % Filter faults for selected component
            faults = app.faultsList(strcmp({app.faultsList.ModelElement}, app.component));
            
            % Check if there are active faults
            if isempty(faults) || ~any([faults.IsActive])
                uialert(app.UIFigure, 'No active faults found for the selected component.', 'No Active Faults', ...
                    'Icon', 'info');
            end

            % ------------- Selection UI: for each fault ask which parameter to tune ------------
            for i = 1:length(faults)


                if faults(i).IsActive
                    faultName = faults(i).Name;

                    % --- find all blocks in Fault Model that belong to this fault ---
                    allBlocks = find_system(app.faultModel, 'FollowLinks', 'on', 'LookUnderMasks', 'all');
                    matchingBlocks = {};
                    for b = 1:length(allBlocks)
                        parts = strsplit(allBlocks{b}, '/');
                        if numel(parts) >= 2 && strcmp(parts{2}, faultName)
                            matchingBlocks{end+1} = allBlocks{b}; %#ok<AGROW>
                        end
                    end

                    % --- build list of tunable behavior parameters AND a mapping to full path ---
                    tunableParams = {};
                    paramChoicesPaths = {}; % same length as tunableParams, each entry is struct with fields BlockPath and ParamName
                    targetTypes = {'Gain', 'Bias', 'Sum', 'Product', 'Saturation', ...
                        'BandLimitedWhiteNoise', 'RandomNumber', 'Fcn', 'MATLABFcn', 'WhiteNoise'};

                    for b = 1:length(matchingBlocks)
                        blk = matchingBlocks{b};
                        % try read block type and dialog params
                        try
                            blkType = get_param(blk, 'BlockType');
                        catch
                            blkType = '';
                        end

                        if ismember(blkType, targetTypes)
                            try
                                dialogParams = get_param(blk, 'DialogParameters');
                                names = fieldnames(dialogParams);
                                for k = 1:numel(names)
                                    % present as "BlockName → Param"
                                    displayLabel = sprintf('%s → %s', get_param(blk, 'Name'), names{k});
                                    tunableParams{end+1} = displayLabel; %#ok<AGROW>
                                    paramChoicesPaths{end+1} = struct('BlockPath', blk, 'ParamName', names{k}); %#ok<AGROW>
                                end
                            catch
                                % skip blocks without params
                            end
                        end
                    end

                    if isempty(tunableParams)
                        uialert(app.UIFigure, sprintf('No tunable parameters found for fault "%s".', faultName), 'No Parameters');
                        continue;
                    end

                    % --- UI dialog for tuning selection (stores string in FaultParamSelection) ---
                    d = uifigure('Name', ['Select parameter for ' faultName], 'Position',[300 300 340 140]);
                    uilabel(d, 'Text', ['Select tunable parameter for fault: ' faultName], 'Position', [20 80 300 30]);
                    dd = uidropdown(d, 'Items', tunableParams, 'Position', [20 50 300 30]);
                    uibutton(d, 'Text', 'OK', 'Position', [120 10 90 30], 'ButtonPushedFcn', @(btn,event) uiresume(d));
                    uiwait(d);
                    try
                        selValue = dd.Value; % string like "White Noise → NoisePower"
                        selIdx = find(strcmp(tunableParams, selValue), 1);
                        % store both string and the mapped path/param
                        app.faultParamSelection.(faultName).Label = selValue;
                        app.faultParamSelection.(faultName).Choice = paramChoicesPaths{selIdx};
                    catch
                        % nothing selected or dialog closed
                        app.faultParamSelection.(faultName) = struct('Label','', 'Choice', []);
                    end
                    delete(d);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SIMULATIONTab = uitab(app.TabGroup);
            app.SIMULATIONTab.Title = 'SIMULATION';
            app.GridLayout2 = uigridlayout(app.SIMULATIONTab);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Fault Name'; 'Fault Status'; 'Effects'; 'Selection'; 'Detected'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.continueButton = uibutton(app.GridLayout2, 'push');
            app.continueButton.ButtonPushedFcn = createCallbackFcn(app, @continueButtonPushed, true);
            app.continueButton.Icon = 'continue_24.png';
            app.continueButton.Text = 'Continue';
            app.pauseButton = uibutton(app.GridLayout2, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Icon = 'pause_24.png';
            app.pauseButton.Text = 'Pause';
            app.stopButton = uibutton(app.GridLayout2, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop_24.png';
            app.stopButton.Text = 'Stop';
            app.runOptionsButton = uibutton(app.GridLayout2, 'push');
            app.runOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @runOptionsButtonPushed, true);
            app.runOptionsButton.Text = 'Run options';
            app.addEffectButton = uibutton(app.GridLayout2, 'push');
            app.addEffectButton.ButtonPushedFcn = createCallbackFcn(app, @addEffectButtonPushed, true);
            app.addEffectButton.Text = 'Add Effect';
            app.runButton = uibutton(app.GridLayout2, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Icon = 'play_24.png';
            app.runButton.Text = 'Run';
            app.saveOutputButton = uibutton(app.GridLayout2, 'push');
            app.saveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @saveOutputButtonPushed, true);
            app.saveOutputButton.Icon = 'saveResults_24.png';
            app.saveOutputButton.Text = 'Save Output';
            app.loadModelButton = uibutton(app.GridLayout2, 'push');
            app.loadModelButton.ButtonPushedFcn = createCallbackFcn(app, @loadModelButtonPushed, true);
            app.loadModelButton.Icon = 'loadInput_24.png';
            app.loadModelButton.Text = 'Load Model';
            app.InstructionPanel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.InstructionPanel);
            app.componentDropDown = uidropdown(app.GridLayout3);
            app.componentDropDown.Items = {};
            app.componentDropDown.ValueChangedFcn = createCallbackFcn(app, @componentDropDownValueChanged, true);
            app.componentDropDown.Value = {};
            app.OKButton = uibutton(app.GridLayout3, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Title = uilabel(app.GridLayout3);
            app.Title.Text = 'Welcome to SFERA';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.whatIsSfera = uihyperlink(app.GridLayout3);
            app.whatIsSfera.HyperlinkClickedFcn = createCallbackFcn(app, @whatIsSferaClicked, true);
            app.whatIsSfera.Text = {'What is SFERA?'; ''};
            app.gettingStarted = uihyperlink(app.GridLayout3);
            app.gettingStarted.URL = 'https://github.com/foselab/SFERA/blob/main/User%20Manual.pdf';
            app.gettingStarted.Text = 'Getting Started...';
            app.loadModelToStartButton = uibutton(app.GridLayout3, 'push');
            app.loadModelToStartButton.ButtonPushedFcn = createCallbackFcn(app, @loadModelToStartButtonPushed, true);
            app.loadModelToStartButton.Text = 'Load a model to start the analysis.';
            app.instructionLabel = uilabel(app.GridLayout3);
            app.instructionLabel.Text = '';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'logo', 'logo3.png');
            app.openModelCheckBox = uicheckbox(app.GridLayout3);
            app.openModelCheckBox.Text = 'Open model ';
            app.faultTuningButton = uibutton(app.GridLayout2, 'push');
            app.faultTuningButton.ButtonPushedFcn = createCallbackFcn(app, @faultTuningButtonPushed, true);
            app.faultTuningButton.Visible = 'off';
            app.faultTuningButton.Text = 'Fault tuning';
            app.RESULTSTab = uitab(app.TabGroup);
            app.RESULTSTab.Title = 'RESULTS';
            app.resultAxes = uiaxes(app.RESULTSTab);
            xlabel(app.resultAxes, 'Simulation time [s]')
            app.resultTable = uitable(app.RESULTSTab);
            app.resultTable.ColumnName = {'Run: Fault'; 'Signal'; 'Show'};
            app.resultTable.RowName = {};
            app.resultTable.CellEditCallback = createCallbackFcn(app, @resultTableCellEdit, true);
            app.logArea = uitextarea(app.GridLayout);
            app.logArea.Editable = 'off';
            app.logArea.Value = {'--- SFERA tool initialized ---'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SFERA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end